classdef robot_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Q3Label                       matlab.ui.control.Label
        Q2Label                       matlab.ui.control.Label
        Q1Label                       matlab.ui.control.Label
        Kut3Label                     matlab.ui.control.Label
        Kut2Label                     matlab.ui.control.Label
        Kut1Label                     matlab.ui.control.Label
        PotvrdiButton                 matlab.ui.control.Button
        ZLabel_2                      matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel_2                      matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        motor3Gauge                   matlab.ui.control.LinearGauge
        motor3GaugeLabel              matlab.ui.control.Label
        motor2Gauge                   matlab.ui.control.LinearGauge
        motor2GaugeLabel              matlab.ui.control.Label
        motor1Gauge                   matlab.ui.control.LinearGauge
        motor1GaugeLabel              matlab.ui.control.Label
        InversekinematicsLabel        matlab.ui.control.Label
        DirectkinematicsLabel         matlab.ui.control.Label
        PosZEditField                 matlab.ui.control.NumericEditField
        PosZEditFieldLabel            matlab.ui.control.Label
        PosYEditField                 matlab.ui.control.NumericEditField
        PosYEditFieldLabel            matlab.ui.control.Label
        PosXEditField                 matlab.ui.control.NumericEditField
        PosXEditFieldLabel            matlab.ui.control.Label
        BrzinarobotaSlider            matlab.ui.control.Slider
        BrzinarobotaSliderLabel       matlab.ui.control.Label
        Zglob3Slider                  matlab.ui.control.Slider
        Zglob3SliderLabel             matlab.ui.control.Label
        Zglob2Slider                  matlab.ui.control.Slider
        Zglob2SliderLabel             matlab.ui.control.Label
        Zglob1Slider                  matlab.ui.control.Slider
        Zglob1SliderLabel             matlab.ui.control.Label
        NainradaButtonGroup           matlab.ui.container.ButtonGroup
        AutomatskiButton              matlab.ui.control.RadioButton
        RucniinversekinematicsButton  matlab.ui.control.RadioButton
        RucnidirectkinematicsButton   matlab.ui.control.RadioButton
        IskljucenoButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        SimulinkModelName % Description
        SimulinkConstantBlockName
        DisplayXBlockHandle
        DisplayYBlockHandle
        DisplayZBlockHandle
    end
    
    methods (Access = public)


        function results = direktna(app)
            a1 = 8.2-1.5;
            a2 = 16;        %%cm
            a3 = 20;
            q1 = app.Zglob1Slider.Value;
            q2 = app.Zglob2Slider.Value;
            q3 = app.Zglob3Slider.Value;
            direktnax = cos(q1)*(a2*sin(q2) + a3*sin(q2+q3));
            direktnay = sin(q1)*(a2*sin(q2) + a3*sin(q2+q3));
            direktnaz = a1 + a2*cos(q2) + a3*cos(q2+q3); 
            app.XLabel.Text=mat2str(direktnax);
            app.YLabel.Text=mat2str(direktnay);
            app.ZLabel.Text=mat2str(direktnaz);
            app.PosXEditField.Value=round(direktnax,2);
            app.PosYEditField.Value=round(direktnay,2);
            app.PosZEditField.Value=round(direktnaz,2);


        end
        
        function results = inverzna(app)
            a1 = 8.2-1.5;
            a2 = 16;        %%cm
            a3 = 20;
            x = app.PosXEditField.Value;
            y = app.PosYEditField.Value;
            z = app.PosZEditField.Value;

            if x>20
            x=20;
            end
            if y>20
            y=20;
            end
            if z>30
            z=25;
            end
            q1 = atan2(y,x)
            q3 = acos((x^2+y^2+(z-a1)^2-a2^2-a3^2)/(2*a2*a3))
            q2 = pi/2 -atan2(z-a1, sqrt(x^2+y^2)) - atan2(sin(q3)*a3, a2+cos(q3)*a3)
            app.Q1Label.Text=mat2str(q1*180/3.14)
            app.Q2Label.Text=mat2str(q2*180/3.14)
            app.Q3Label.Text=mat2str(q3*180/3.14)
            if q1>-180 && q1<180
            app.Zglob1Slider.Value=round(str2double(app.Q1Label.Text),2);
            end
            if q2>0 && q2<100
            app.Zglob2Slider.Value=round(str2double(app.Q2Label.Text),2);
            end
            if q3>0 && q3<160
            app.Zglob3Slider.Value=round(str2double(app.Q3Label.Text),2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulinkConstantBlockName = "simulation_three_motors";
           % app.DisplayXBlockHandle = getSimulinkBlockHandle('simulation_three_motors/X');
           %app.DisplayYBlockHandle = getSimulinkBlockHandle('simulation_three_motors/Y');
           % app.DisplayZBlockHandle = getSimulinkBlockHandle('simulation_three_motors/Z');
            global hApp;
            hApp= app;
            app.SimulinkModelName='simulation_three_motors';
            app.BrzinarobotaSlider.Enable = false;
            app.PosXEditField.Enable = false;
            app.PosYEditField.Enable = false;
            app.PosZEditField.Enable = false;
            app.Zglob1Slider.Enable = false;
            app.Zglob2Slider.Enable = false;
            app.Zglob3Slider.Enable = false; 
            
            set_param('simulation_three_motors/nacin_rada','Value',mat2str(1))
            
        end

        % Callback function: Zglob1Slider, Zglob1Slider
        function Join1SliderValueChanged(app, event)
            kut1 = app.Zglob1Slider.Value;
            set_param('simulation_three_motors/Joint1_value','Value', mat2str(round(kut1,2)));
   
            direktna(app);

        end

        % Value changed function: Zglob2Slider
        function Zglob2SliderValueChanged(app, event)
            kut2 = app.Zglob2Slider.Value;
            set_param('simulation_three_motors/Joint2_value','Value', mat2str(round(kut2,2)));
            direktna(app);
        end

        % Value changed function: Zglob3Slider
        function Zglob3SliderValueChanged(app, event)
            kut3 = app.Zglob3Slider.Value;
            set_param('simulation_three_motors/Joint3_value','Value', mat2str(round(kut3,2)));
            direktna(app);
        end

        % Value changed function: BrzinarobotaSlider
        function BrzinarobotaSliderValueChanged(app, event)
            brzina = app.BrzinarobotaSlider.Value;
            set_param('simulation_three_motors/Kp','Gain', mat2str(round(brzina,2)));
            set_param('simulation_three_motors/Kp1','Gain', mat2str(round(brzina,2)));
            set_param('simulation_three_motors/Kp2','Gain', mat2str(round(brzina,2)));
        end

        % Value changed function: PosXEditField
        function PosXEditFieldValueChanged(app, event)
         
            inverzna(app);
        end

        % Value changed function: PosYEditField
        function PosYEditFieldValueChanged(app, event)
         
            inverzna(app);
        end

        % Value changed function: PosZEditField
        function PosZEditFieldValueChanged(app, event)
 
            inverzna(app);
        end

        % Selection changed function: NainradaButtonGroup
        function NainradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.NainradaButtonGroup.SelectedObject;
            if selectedButton == app.IskljucenoButton
                app.BrzinarobotaSlider.Enable = false;
                app.PosXEditField.Enable = false;
                app.PosYEditField.Enable = false;
                app.PosZEditField.Enable = false;
                app.Zglob1Slider.Enable = false;
                app.Zglob2Slider.Enable = false;
                app.Zglob3Slider.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(1))
                
            end
            if selectedButton == app.RucnidirectkinematicsButton
                app.Zglob1Slider.Enable = true;
                app.Zglob2Slider.Enable = true;
                app.Zglob3Slider.Enable = true; 
                app.BrzinarobotaSlider.Enable = true;
                app.PosXEditField.Enable = true;
                app.PosYEditField.Enable = true;
                app.PosZEditField.Enable = true;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(2))
                
            end

            if selectedButton == app.RucniinversekinematicsButton
                app.BrzinarobotaSlider.Enable = true;
                app.PosXEditField.Enable = true;
                app.PosYEditField.Enable = true;
                app.PosZEditField.Enable = true;
                app.Zglob1Slider.Enable = true;
                app.Zglob2Slider.Enable = true;
                app.Zglob3Slider.Enable = true;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(3))
                
            end


            if selectedButton == app.AutomatskiButton
                app.BrzinarobotaSlider.Enable = false;
                app.PosXEditField.Enable = false;
                app.PosYEditField.Enable = false;
                app.PosZEditField.Enable = false;
                app.Zglob1Slider.Enable = false;
                app.Zglob2Slider.Enable = false;
                app.Zglob3Slider.Enable = false;
                set_param('simulation_three_motors/nacin_rada','Value',mat2str(4))
            end
        end

        % Button pushed function: PotvrdiButton
        function PotvrdiButtonPushed(app, event)
            posx = app.PosXEditField.Value;
            set_param('simulation_three_motors/PosX','Value', mat2str(posx))
            posy = app.PosYEditField.Value;
            set_param('simulation_three_motors/PosY','Value', mat2str(posy))
            posz = app.PosZEditField.Value;
            set_param('simulation_three_motors/PosZ','Value', mat2str(posz))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NainradaButtonGroup = uibuttongroup(app.UIFigure);
            app.NainradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NainradaButtonGroupSelectionChanged, true);
            app.NainradaButtonGroup.Title = 'Način rada ';
            app.IskljucenoButton = uiradiobutton(app.NainradaButtonGroup);
            app.IskljucenoButton.Text = 'Iskljuceno';
            app.IskljucenoButton.Value = true;
            app.RucnidirectkinematicsButton = uiradiobutton(app.NainradaButtonGroup);
            app.RucnidirectkinematicsButton.Text = 'Rucni - direct kinematics';
            app.RucniinversekinematicsButton = uiradiobutton(app.NainradaButtonGroup);
            app.RucniinversekinematicsButton.Text = 'Rucni - inverse kinematics';
            app.AutomatskiButton = uiradiobutton(app.NainradaButtonGroup);
            app.AutomatskiButton.Text = 'Automatski';
            app.Zglob1SliderLabel = uilabel(app.UIFigure);
            app.Zglob1SliderLabel.Text = 'Zglob1';
            app.Zglob1Slider = uislider(app.UIFigure);
            app.Zglob1Slider.Limits = [-180 180];
            app.Zglob1Slider.ValueChangedFcn = createCallbackFcn(app, @Join1SliderValueChanged, true);
            app.Zglob1Slider.ValueChangingFcn = createCallbackFcn(app, @Join1SliderValueChanged, true);
            app.Zglob2SliderLabel = uilabel(app.UIFigure);
            app.Zglob2SliderLabel.Text = 'Zglob2';
            app.Zglob2Slider = uislider(app.UIFigure);
            app.Zglob2Slider.ValueChangedFcn = createCallbackFcn(app, @Zglob2SliderValueChanged, true);
            app.Zglob3SliderLabel = uilabel(app.UIFigure);
            app.Zglob3SliderLabel.Text = 'Zglob3';
            app.Zglob3Slider = uislider(app.UIFigure);
            app.Zglob3Slider.Limits = [0 160];
            app.Zglob3Slider.ValueChangedFcn = createCallbackFcn(app, @Zglob3SliderValueChanged, true);
            app.Zglob3Slider.Value = 20;
            app.BrzinarobotaSliderLabel = uilabel(app.UIFigure);
            app.BrzinarobotaSliderLabel.Text = 'Brzina robota';
            app.BrzinarobotaSlider = uislider(app.UIFigure);
            app.BrzinarobotaSlider.Limits = [0 2];
            app.BrzinarobotaSlider.ValueChangedFcn = createCallbackFcn(app, @BrzinarobotaSliderValueChanged, true);
            app.BrzinarobotaSlider.Value = 0.3;
            app.PosXEditFieldLabel = uilabel(app.UIFigure);
            app.PosXEditFieldLabel.Text = 'PosX';
            app.PosXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosXEditField.ValueChangedFcn = createCallbackFcn(app, @PosXEditFieldValueChanged, true);
            app.PosYEditFieldLabel = uilabel(app.UIFigure);
            app.PosYEditFieldLabel.Text = 'PosY';
            app.PosYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosYEditField.ValueChangedFcn = createCallbackFcn(app, @PosYEditFieldValueChanged, true);
            app.PosZEditFieldLabel = uilabel(app.UIFigure);
            app.PosZEditFieldLabel.Text = 'PosZ';
            app.PosZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosZEditField.ValueChangedFcn = createCallbackFcn(app, @PosZEditFieldValueChanged, true);
            app.DirectkinematicsLabel = uilabel(app.UIFigure);
            app.DirectkinematicsLabel.Text = 'Direct kinematics';
            app.InversekinematicsLabel = uilabel(app.UIFigure);
            app.InversekinematicsLabel.Text = 'Inverse kinematics';
            app.motor1GaugeLabel = uilabel(app.UIFigure);
            app.motor1GaugeLabel.Text = 'motor1';
            app.motor1Gauge = uigauge(app.UIFigure, 'linear');
            app.motor1Gauge.Limits = [0 360];
            app.motor2GaugeLabel = uilabel(app.UIFigure);
            app.motor2GaugeLabel.Text = 'motor2';
            app.motor2Gauge = uigauge(app.UIFigure, 'linear');
            app.motor3GaugeLabel = uilabel(app.UIFigure);
            app.motor3GaugeLabel.Text = 'motor3';
            app.motor3Gauge = uigauge(app.UIFigure, 'linear');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X=';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y=';
            app.ZLabel_2 = uilabel(app.UIFigure);
            app.ZLabel_2.Text = 'Z=';
            app.PotvrdiButton = uibutton(app.UIFigure, 'push');
            app.PotvrdiButton.ButtonPushedFcn = createCallbackFcn(app, @PotvrdiButtonPushed, true);
            app.PotvrdiButton.Text = 'Potvrdi';
            app.Kut1Label = uilabel(app.UIFigure);
            app.Kut1Label.Text = 'Kut1 = ';
            app.Kut2Label = uilabel(app.UIFigure);
            app.Kut2Label.Text = 'Kut2 = ';
            app.Kut3Label = uilabel(app.UIFigure);
            app.Kut3Label.Text = 'Kut3 = ';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Text = 'Q1';
            app.Q2Label = uilabel(app.UIFigure);
            app.Q2Label.Text = 'Q2';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Text = 'Q3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end